classdef testGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        pickhostimageButton       matlab.ui.control.Button
        DISPLAYPanel              matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        Label                     matlab.ui.control.Label
        Label2                    matlab.ui.control.Label
        EmbedwatermarkLabel       matlab.ui.control.Label
        pickwatermarkimageButton  matlab.ui.control.Button
        EmbedButton               matlab.ui.control.Button
        pickkeyButton             matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        ComparePanel              matlab.ui.container.Panel
        PSNRButton                matlab.ui.control.Button
        WPSNRButton               matlab.ui.control.Button
        SSIMButton                matlab.ui.control.Button
        NCButton                  matlab.ui.control.Button
        VisualButton              matlab.ui.control.Button
        HistogramButton           matlab.ui.control.Button
    end


    properties (Access = public)
       fullname; %host image path
       fullname_2; %watermark image path
       fullname_3; %key path
       ImageFile; % host image
       ImageFile_2; %watermark image
       WatermarkedImage; %watermarked image path
       
 
    end


    methods (Access = private)

        % Button pushed function: pickhostimageButton
        function pickhostimageButtonPushed(app, event)

% Remove title, axis labels, and tick labels
xlabel(app.UIAxes, []);
ylabel(app.UIAxes, []);
app.UIAxes.XAxis.TickLabels = {};
app.UIAxes.YAxis.TickLabels = {};
% Display image and stretch to fill axes
[filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select Host Image to Open');
    app.fullname = [filepath, filename];
    
    if(isequal(filename,0) ) % phai chon anh
         f = errordlg('Must pick a file');
    else
        app.ImageFile = imread(app.fullname); %host image
        I =imshow(app.ImageFile, 'Parent', app.UIAxes);      
        % Set limits of axes
        app.UIAxes.XLim = [0 I.XData(2)];
        app.UIAxes.YLim = [0 I.YData(2)];
        app.pickwatermarkimageButton.Enable = 'on';
    end
    

        end

        % Button pushed function: pickwatermarkimageButton
        function pickwatermarkimageButtonPushed(app, event)
xlabel(app.UIAxes_2, []);
ylabel(app.UIAxes_2, []);
app.UIAxes_2.XAxis.TickLabels = {};
app.UIAxes_2.YAxis.TickLabels = {};

% Display image and stretch to fill axes
[filename_2,filepath_2] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select Watermark Image to Open');
    app.fullname_2 = [filepath_2, filename_2]; %watermark image
    
    if(isequal(filename_2,0) ) % phai chon anh
         f = errordlg('Must pick a file');
    else
        app.ImageFile_2 = imread(app.fullname_2);
        I_2=imshow(app.ImageFile_2, 'Parent', app.UIAxes_2);    
        app.UIAxes_2.XLim = [0 I_2.XData(2)];
        app.UIAxes_2.YLim = [0 I_2.YData(2)];
        app.pickkeyButton.Enable = 'on';
    end
    
% Set limits of axes

        end

        % Button pushed function: EmbedButton
        function EmbedButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
            'Message','Embedding watermark...','Indeterminate','on');
            check = true;
            while(check)
            d.Message = 'Processing the data';    
            app.WatermarkedImage=Embed(app.fullname_2,app.fullname,app.fullname_3);
            
            check=false;
            end
            %hien title
            title(app.UIAxes, "HostImage");
            title(app.UIAxes_2, "WatermarkedImage");
            %hien anh
            I=imshow(app.ImageFile, 'Parent', app.UIAxes); 
            I_2=imshow(app.WatermarkedImage, 'Parent', app.UIAxes_2); 
            % gioi han khung anh
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            app.UIAxes_2.XLim = [0 I_2.XData(2)];
            app.UIAxes_2.YLim = [0 I_2.YData(2)];
            %show save button
            app.SaveButton.Enable = 'on';
            app.ComparePanel.Visible = 'on';
            
        end

        % Button pushed function: pickkeyButton
        function pickkeyButtonPushed(app, event)
            % Display image and stretch to fill axes
    [filename_3,filepath_3] = uigetfile({'*.*;*.txt;*.mat;'}, 'Select key to Open');
    if(isequal(filename_3,0) ) % phai chon anh
         f = errordlg('Must pick a file');
    else
    app.fullname_3 = [filepath_3, filename_3];
    end
    if ((isequal(app.fullname_2,0)==0) && (isequal(app.fullname,0)==0) && (isequal(app.fullname_3,0)==0))
                app.EmbedButton.Enable='on';
    end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [filename,pathname,filterindex]=uiputfile({'*.jpg;*.jpeg;*.gif;*.tif;*.tiff;*.rgb;*.bmp;*.png',...
        'All support image files';'*.jpg;*.jpeg','JPEG files(*.jpg;*.jpeg)';'*.gif','GIF files (*.gif)';...
        '*.tif;*.tiff','TIFF files (*.tif;*.tiff)';'*.bmp','Bitmap files (*.bmp)';'*.png','PNG files (*.png)'},'Luu dang file anh');
    var = strcat(pathname,filename);
    imwrite(uint8(app.WatermarkedImage),var);
    app.WatermarkedImage = var;
    
    %show button of next process
    app.PSNRButton.Enable = 'on';
    app.WPSNRButton.Enable = 'on';
    app.SSIMButton.Enable = 'on';
    app.NCButton.Enable = 'on';
    app.VisualButton.Enable = 'on';
    app.HistogramButton.Enable = 'on';
        end

        % Button pushed function: PSNRButton
        function PSNRButtonPushed(app, event)
            app.Label2.Visible = 'on';
            app.Label2.Text = string(psnr(imread(app.fullname), imread(app.WatermarkedImage)));
        end

        % Button pushed function: SSIMButton
        function SSIMButtonPushed(app, event)
            app.Label2.Visible = 'on';
            app.Label2.Text = string(ssim(imread(app.WatermarkedImage), imread(app.fullname)));
        end

        % Button pushed function: NCButton
        function NCButtonPushed(app, event)
            addpath('./comparison function');
            app.Label2.Visible = 'on';
            app.Label2.Text = string(nc(app.WatermarkedImage,app.fullname));
        end

        % Button pushed function: WPSNRButton
        function WPSNRButtonPushed(app, event)
            addpath('./comparison function');
            app.Label2.Visible = 'on';
            a=double(imread(app.fullname));
            b=double(imread(app.WatermarkedImage));
            [psnr,wpsnr1]=wpsnr(a,b);
            app.Label2.Text = string(wpsnr1);
        end

        % Button pushed function: VisualButton
        function VisualButtonPushed(app, event)
            %hien anh
            I=imshow(app.ImageFile, 'Parent', app.UIAxes); 
            I_2=imshow(app.WatermarkedImage, 'Parent', app.UIAxes_2); 
            % gioi han khung anh
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            app.UIAxes_2.XLim = [0 I_2.XData(2)];
            app.UIAxes_2.YLim = [0 I_2.YData(2)];
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            a=imread(app.fullname);
            b=imread(app.WatermarkedImage);
            %hien anh
            I=histogram(a, 'Parent', app.UIAxes); 
            I_2=histogram(b, 'Parent', app.UIAxes_2); 
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.pickhostimageButton = uibutton(app.UIFigure, 'push');
            app.pickhostimageButton.ButtonPushedFcn = createCallbackFcn(app, @pickhostimageButtonPushed, true);
            app.pickhostimageButton.Text = 'pick host image';
            app.DISPLAYPanel = uipanel(app.UIFigure);
            app.DISPLAYPanel.Title = 'DISPLAY';
            app.UIAxes = uiaxes(app.DISPLAYPanel);
            title(app.UIAxes, 'Host Image')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.DISPLAYPanel);
            title(app.UIAxes_2, 'Watermark Image')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.Label = uilabel(app.DISPLAYPanel);
            app.Label.Visible = 'off';
            app.Label2 = uilabel(app.DISPLAYPanel);
            app.Label2.Visible = 'off';
            app.Label2.Text = 'Label2';
            app.EmbedwatermarkLabel = uilabel(app.UIFigure);
            app.EmbedwatermarkLabel.Text = 'Embed watermark';
            app.pickwatermarkimageButton = uibutton(app.UIFigure, 'push');
            app.pickwatermarkimageButton.ButtonPushedFcn = createCallbackFcn(app, @pickwatermarkimageButtonPushed, true);
            app.pickwatermarkimageButton.Enable = 'off';
            app.pickwatermarkimageButton.Text = 'pick watermark image';
            app.EmbedButton = uibutton(app.UIFigure, 'push');
            app.EmbedButton.ButtonPushedFcn = createCallbackFcn(app, @EmbedButtonPushed, true);
            app.EmbedButton.Enable = 'off';
            app.EmbedButton.Text = 'Embed';
            app.pickkeyButton = uibutton(app.UIFigure, 'push');
            app.pickkeyButton.ButtonPushedFcn = createCallbackFcn(app, @pickkeyButtonPushed, true);
            app.pickkeyButton.Enable = 'off';
            app.pickkeyButton.Text = 'pick key';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.ComparePanel = uipanel(app.UIFigure);
            app.ComparePanel.Title = 'Compare';
            app.PSNRButton = uibutton(app.ComparePanel, 'push');
            app.PSNRButton.ButtonPushedFcn = createCallbackFcn(app, @PSNRButtonPushed, true);
            app.PSNRButton.Enable = 'off';
            app.PSNRButton.Text = 'PSNR';
            app.WPSNRButton = uibutton(app.ComparePanel, 'push');
            app.WPSNRButton.ButtonPushedFcn = createCallbackFcn(app, @WPSNRButtonPushed, true);
            app.WPSNRButton.Enable = 'off';
            app.WPSNRButton.Text = 'WPSNR';
            app.SSIMButton = uibutton(app.ComparePanel, 'push');
            app.SSIMButton.ButtonPushedFcn = createCallbackFcn(app, @SSIMButtonPushed, true);
            app.SSIMButton.Enable = 'off';
            app.SSIMButton.Text = 'SSIM';
            app.NCButton = uibutton(app.ComparePanel, 'push');
            app.NCButton.ButtonPushedFcn = createCallbackFcn(app, @NCButtonPushed, true);
            app.NCButton.Enable = 'off';
            app.NCButton.Text = 'NC';
            app.VisualButton = uibutton(app.ComparePanel, 'push');
            app.VisualButton.ButtonPushedFcn = createCallbackFcn(app, @VisualButtonPushed, true);
            app.VisualButton.Enable = 'off';
            app.VisualButton.Text = 'Visual';
            app.HistogramButton = uibutton(app.ComparePanel, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Enable = 'off';
            app.HistogramButton.Text = 'Histogram';
        end
    end

    methods (Access = public)

        % Construct app
        function app = testGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end